classdef biofilm_gui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TabGroup                     matlab.ui.container.TabGroup
        ParticulatesTab              matlab.ui.container.Tab
        ParticulateProperties        matlab.ui.control.Table
        AddParticulateButton         matlab.ui.control.Button
        GrowthModelTable             matlab.ui.control.Table
        UITable                      matlab.ui.control.Table
        GrowthModelLabel             matlab.ui.control.Label
        SubstrateDependantProperitesLabel  matlab.ui.control.Label
        ParticulateNameLabel         matlab.ui.control.Label
        ParticulateNameEditField     matlab.ui.control.EditField
        GeneralPropertiesTab         matlab.ui.container.Tab
        FinalTimeEditFieldLabel      matlab.ui.control.Label
        FinalTimeEditField           matlab.ui.control.NumericEditField
        PlotFrequencyEditFieldLabel  matlab.ui.control.Label
        PlotFrequencyEditField       matlab.ui.control.NumericEditField
        VolumeEditFieldLabel         matlab.ui.control.Label
        VolumeEditField              matlab.ui.control.NumericEditField
        FlowRateEditFieldLabel       matlab.ui.control.Label
        FlowRateEditField            matlab.ui.control.NumericEditField
        SubstratesTab                matlab.ui.container.Tab
        AddSubstrateButton           matlab.ui.control.Button
        SubstrateProperties          matlab.ui.control.Table
        SubstrateNameEditFieldLabel  matlab.ui.control.Label
        SubstrateNameEditField       matlab.ui.control.EditField
    end

    methods (Access = private)

        % Code that executes after component creation
        function save(app)
            startingFolder = 'C:\Users\kaico\OneDrive\Documents\Biofilm_Project';
                if ~exist(startingFolder, 'dir')
                % If that folder doesn't exist, just start in the current folder.
                    startingFolder = pwd;
                end
                % Put in the name of the mat file that the user wants to save.
                % defaultFileName = fullfile(startingFolder, '*.mat')
                [file,path] = uiputfile('myMatrix.mat','Save file name');
                if file == 0
                    % User clicked the Cancel button.
                    return;
                end
                fullFileName = fullfile(path, file);
                save(fullFileName)
        end

        % Button pushed function: AddSubstrateButton
        function AddSubstrateButtonPushed(app, event)
            length2 = size(app.GrowthModelTable.ColumnName,1)+1;
            app.GrowthModelTable.Data = [app.GrowthModelTable.Data zeros(size(app.GrowthModelTable.Data,1),1)];
            app.SubstrateProperties.Data = [app.SubstrateProperties.Data; zeros(1,3)];
            app.GrowthModelTable.ColumnName{length2,1} = app.SubstrateNameEditField.Value     
        end

        % Button pushed function: AddParticulateButton
        function AddParticulateButtonPushed(app, event)
            length1 = size(app.ParticulateProperties.ColumnName,1);
            length2 = size(app.GrowthModelTable.ColumnName,1);
            num_row = size(app.ParticulateProperties.RowName,1)+1;
            app.ParticulateProperties.RowName{num_row,1} = app.ParticulateNameEditField.Value;
            app.ParticulateProperties.Data = [app.ParticulateProperties.Data; zeros(1,length1)];
            app.GrowthModelTable.Data = [app.GrowthModelTable.Data; zeros(1,length2)];
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ParticulatesTab = uitab(app.TabGroup);
            app.ParticulatesTab.Title = 'Particulates';
            app.ParticulateProperties = uitable(app.ParticulatesTab);
            app.ParticulateProperties.ColumnName = {'Mu Max'; 'Xo'};
            app.ParticulateProperties.RowName = {};
            app.AddParticulateButton = uibutton(app.ParticulatesTab, 'push');
            app.AddParticulateButton.ButtonPushedFcn = createCallbackFcn(app, @AddParticulateButtonPushed, true);
            app.AddParticulateButton.Text = 'Add Particulate';
            app.GrowthModelTable = uitable(app.ParticulatesTab);
            app.GrowthModelTable.ColumnName = {''};
            app.GrowthModelTable.RowName = {};
            app.UITable = uitable(app.ParticulatesTab);
            app.UITable.ColumnName = {'Yx'; 'Km'};
            app.UITable.RowName = {};
            app.GrowthModelLabel = uilabel(app.ParticulatesTab);
            app.GrowthModelLabel.Text = 'Growth Model';
            app.SubstrateDependantProperitesLabel = uilabel(app.ParticulatesTab);
            app.SubstrateDependantProperitesLabel.Text = 'Substrate Dependant Properites';
            app.ParticulateNameLabel = uilabel(app.ParticulatesTab);
            app.ParticulateNameLabel.Text = 'Particulate Name:';
            app.ParticulateNameEditField = uieditfield(app.ParticulatesTab, 'text');
            app.GeneralPropertiesTab = uitab(app.TabGroup);
            app.GeneralPropertiesTab.Title = 'General Properties';
            app.FinalTimeEditFieldLabel = uilabel(app.GeneralPropertiesTab);
            app.FinalTimeEditFieldLabel.Text = 'Final Time';
            app.FinalTimeEditField = uieditfield(app.GeneralPropertiesTab, 'numeric');
            app.PlotFrequencyEditFieldLabel = uilabel(app.GeneralPropertiesTab);
            app.PlotFrequencyEditFieldLabel.Text = 'Plot Frequency';
            app.PlotFrequencyEditField = uieditfield(app.GeneralPropertiesTab, 'numeric');
            app.VolumeEditFieldLabel = uilabel(app.GeneralPropertiesTab);
            app.VolumeEditFieldLabel.Text = 'Volume';
            app.VolumeEditField = uieditfield(app.GeneralPropertiesTab, 'numeric');
            app.FlowRateEditFieldLabel = uilabel(app.GeneralPropertiesTab);
            app.FlowRateEditFieldLabel.Text = 'Flow Rate';
            app.FlowRateEditField = uieditfield(app.GeneralPropertiesTab, 'numeric');
            app.SubstratesTab = uitab(app.TabGroup);
            app.SubstratesTab.Title = 'Substrates';
            app.AddSubstrateButton = uibutton(app.SubstratesTab, 'push');
            app.AddSubstrateButton.ButtonPushedFcn = createCallbackFcn(app, @AddSubstrateButtonPushed, true);
            app.AddSubstrateButton.Text = 'Add Substrate';
            app.SubstrateProperties = uitable(app.SubstratesTab);
            app.SubstrateProperties.ColumnName = {'So'; 'Daq'; 'De'};
            app.SubstrateProperties.RowName = {};
            app.SubstrateNameEditFieldLabel = uilabel(app.SubstratesTab);
            app.SubstrateNameEditFieldLabel.Text = 'Substrate Name:';
            app.SubstrateNameEditField = uieditfield(app.SubstratesTab, 'text');
        end
    end

    methods (Access = public)

        % Construct app
        function app = biofilm_gui

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @save)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end